classdef PPGFeat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGFeatUIFigure             matlab.ui.Figure
        OffEditField                matlab.ui.control.NumericEditField
        SLabel_2                    matlab.ui.control.Label
        canddpresentEditField       matlab.ui.control.NumericEditField
        canddpresentEditFieldLabel  matlab.ui.control.Label
        fEditField                  matlab.ui.control.NumericEditField
        fEditFieldLabel             matlab.ui.control.Label
        eEditField                  matlab.ui.control.NumericEditField
        eEditFieldLabel             matlab.ui.control.Label
        dEditField                  matlab.ui.control.NumericEditField
        dEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        cmLabel                     matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        Yes0NoLabel                 matlab.ui.control.Label
        uEditField                  matlab.ui.control.NumericEditField
        wLabel                      matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        zLabel                      matlab.ui.control.Label
        xLabel                      matlab.ui.control.Label
        vEditField                  matlab.ui.control.NumericEditField
        wEditField                  matlab.ui.control.NumericEditField
        yLabel                      matlab.ui.control.Label
        OnEditField                 matlab.ui.control.NumericEditField
        onLabel                     matlab.ui.control.Label
        SpEditField                 matlab.ui.control.NumericEditField
        DLabel                      matlab.ui.control.Label
        DnEditField                 matlab.ui.control.NumericEditField
        dnLabel                     matlab.ui.control.Label
        DpEditField                 matlab.ui.control.NumericEditField
        SLabel                      matlab.ui.control.Label
        DataTextArea                matlab.ui.control.TextArea
        DataTextAreaLabel           matlab.ui.control.Label
        IDEditField                 matlab.ui.control.NumericEditField
        IDLabel                     matlab.ui.control.Label
        Min2EditField               matlab.ui.control.NumericEditField
        Min2EditFieldLabel          matlab.ui.control.Label
        Min1EditFieldLabel          matlab.ui.control.Label
        Min1EditField               matlab.ui.control.NumericEditField
        Panel                       matlab.ui.container.Panel
        SegCombSQIEdit              matlab.ui.control.NumericEditField
        CorrelationLabel_4          matlab.ui.control.Label
        SegSkewSQIEditField         matlab.ui.control.NumericEditField
        SkewnessLabel_2             matlab.ui.control.Label
        SegCorrSQIEditField         matlab.ui.control.NumericEditField
        CorrelationLabel_3          matlab.ui.control.Label
        SegmentSQILabel             matlab.ui.control.Label
        CycleCombSQIEditField       matlab.ui.control.NumericEditField
        CorrelationLabel_2          matlab.ui.control.Label
        GenerateoutputLabel         matlab.ui.control.Label
        SavecsvButton               matlab.ui.control.Button
        ProcesseveryentrywithoutmanualinputLabel  matlab.ui.control.Label
        ProcessAllButton            matlab.ui.control.Button
        CycleSQILabel               matlab.ui.control.Label
        CycleSkewSQIEditField       matlab.ui.control.NumericEditField
        SkewnessLabel               matlab.ui.control.Label
        CycleCorrSQIEditField       matlab.ui.control.NumericEditField
        CorrelationLabel            matlab.ui.control.Label
        LoadFolderButton            matlab.ui.control.Button
        PlotpressedLamp             matlab.ui.control.Lamp
        PlotpressedLampLabel        matlab.ui.control.Label
        SavematButton               matlab.ui.control.Button
        GotonextwaveformLabel       matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel  matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        Plotallwaveformsusingmin1andmin2Label  matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        LoadFileButton              matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        sLabel                      matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        SegmentCheck                matlab.ui.control.CheckBox
        SegmentEditField            matlab.ui.control.NumericEditField
        SegmentEditFieldLabel       matlab.ui.control.Label
        ResampleCheck               matlab.ui.control.CheckBox
        ResampleEditField           matlab.ui.control.NumericEditField
        ResampleEditFieldLabel      matlab.ui.control.Label
        EnterSamplingfreqfilterfreqLabel  matlab.ui.control.Label
        FHEditField                 matlab.ui.control.NumericEditField
        FHEditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        F1Label                     matlab.ui.control.Label
        FsHzEditField               matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        PPGAnalObject
    end

    methods (Access = private)

        function PlotPPG(app)
            app.IDEditField.Value = app.PPGAnalObject.Sub_ID;

            plot(app.UIAxes4, app.PPGAnalObject.loaded_data(app.PPGAnalObject.data_idx,:))  %Plot first RAW waveform

            %show data numbr in the text area 
            Dat1 = num2str(app.PPGAnalObject.size_data(1) * app.PPGAnalObject.num_seg);
            Dat2 = num2str(app.PPGAnalObject.total_seg_idx);
            Dat = Dat2 + " of " + Dat1;
            app.DataTextArea.Value = Dat;

            %plot the filtered PPG and show the maxima and minima
            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.total_seg_idx ,:);
            PPG = plot(app.UIAxes, filtered_data ); %Plot filtered PPG
            %addToolbarExplorationButtons(PPG);
            grid(app.UIAxes, "on")

            [min1, min2, cycle_corr, cycle_skew, seg_corr, seg_skew, cycle_qual, seg_qual] = app.PPGAnalObject.FindBestCycle();
            
            if (isnan(min1))
                msgbox('Could not detect any cycles in segment', 'Error', 'error')
            else
                app.Min1EditField.Value = min1;
                app.Min2EditField.Value = min2;
                app.CycleCorrSQIEditField.Value = cycle_corr;
                app.CycleSkewSQIEditField.Value = cycle_skew;
                app.SegCorrSQIEditField.Value = seg_corr;
                app.SegSkewSQIEditField.Value = seg_skew;
                app.CycleCombSQIEditField.Value = cycle_qual;
                app.SegCombSQIEdit.Value = seg_qual;

                datatip(PPG, app.Min1EditField.Value,filtered_data(app.Min1EditField.Value),'FontSize',8);
                datatip(PPG, app.Min2EditField.Value,filtered_data(app.Min2EditField.Value),'FontSize',8);
            end
        end
        
        function PlotFiducial(app, PPGAnal)
            seg_index = PPGAnal.total_seg_idx;

            %Update fields after with ppg_anal values
            if ~isnan(PPGAnal.OnSpDnDpOff_time(1))
                app.OnEditField.Value = PPGAnal.OnSpDnDpOff_time(1);
            else
                app.OnEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(2))
                app.SpEditField.Value = PPGAnal.OnSpDnDpOff_time(2);
            else
                app.SpEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(3))
                app.DnEditField.Value = PPGAnal.OnSpDnDpOff_time(3);
            else
                app.DnEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(4))
                app.DpEditField.Value = PPGAnal.OnSpDnDpOff_time(4);
            else
                app.DpEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(5))
                app.OffEditField.Value = PPGAnal.OnSpDnDpOff_time(5);
            else
                app.OffEditField.Value = [];
            end

            if ~isnan(PPGAnal.uxvw_time(1))
                app.uEditField.Value = PPGAnal.uxvw_time(1);
            else
                app.uEditField.Value = [];
            end
            if ~isnan(PPGAnal.uxvw_time(2))
                app.xEditField.Value = PPGAnal.uxvw_time(2);
            else
                app.xEditField.Value = [];
            end
            if ~isnan(PPGAnal.uxvw_time(3))
                app.vEditField.Value = PPGAnal.uxvw_time(3);
            else
                app.vEditField.Value = [];
            end
            if ~isnan(PPGAnal.uxvw_time(4))
                app.wEditField.Value = PPGAnal.uxvw_time(4);
            else
                app.wEditField.Value = [];
            end

            if ~isnan(PPGAnal.abcdef_time(1))
                app.aEditField.Value = PPGAnal.abcdef_time(1);
            else
                app.aEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(2))
                app.bEditField.Value = PPGAnal.abcdef_time(2);
            else
                app.bEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(3))
                app.cEditField.Value = PPGAnal.abcdef_time(3);
            else
                app.cEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(4))
                app.dEditField.Value = PPGAnal.abcdef_time(4);
            else
                app.dEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(5))
                app.eEditField.Value = PPGAnal.abcdef_time(5);
            else
                app.eEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(6))
                app.fEditField.Value = PPGAnal.abcdef_time(6);
            else
                app.fEditField.Value = [];
            end

            app.canddpresentEditField.Value = PPGAnal.c_d_pres;

            %plot the PPG segment
            P_seg = plot(app.UIAxes2,PPGAnal.seg);
            grid(app.UIAxes2,"on")

            line(app.UIAxes2,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            for i = 1:5
                if (~isnan(PPGAnal.OnSpDnDpOff_time(i)))
                    datatip(P_seg, PPGAnal.OnSpDnDpOff_time(i),PPGAnal.OnSpDnDpOff(i),'FontSize',8);
                end
            end

            %plot VPG
            P_vpg = plot(app.UIAxes6, PPGAnal.VPG);
            grid(app.UIAxes6,"on")

            line(app.UIAxes6,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            for i = 1:4
                if (~isnan(PPGAnal.uxvw_time(i)))
                    datatip(P_vpg, PPGAnal.uxvw_time(i),PPGAnal.uxvw(i),'FontSize',8);
                end
            end

            %plot APG
            P_apg = plot(app.UIAxes5, PPGAnal.APG);
            grid(app.UIAxes5,"on")

            line(app.UIAxes5,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment    
        
            for i = 1:6
                if (~isnan(PPGAnal.abcdef_time(i)))
                    datatip(P_apg, PPGAnal.abcdef_time(i),PPGAnal.abcdef(i),'FontSize',8);
                end
            end

            %plot JPG
            plot(app.UIAxes3, PPGAnal.JPG)
            grid(app.UIAxes3,"on")

            line(app.UIAxes3,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment
        
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PPGAnalObject = ppg_anal;
            PPGFeatUIFigureThemeChanged(app);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

            if (app.ResampleCheck.Value == true)
                res = app.PPGAnalObject.LoadPPG(app.FsHzEditField.Value, app.ResampleEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            else
                res = app.PPGAnalObject.LoadPPG(app.FsHzEditField.Value, app.FsHzEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            end
            
            if (res)
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                app.PlotPPG();
            end
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            if (app.ResampleCheck.Value == true)
                res = app.PPGAnalObject.LoadDirectory(app.FsHzEditField.Value, app.ResampleEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            else
                res = app.PPGAnalObject.LoadDirectory(app.FsHzEditField.Value, app.FsHzEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            end
            if (res)
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                app.PlotPPG();
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            app.PlotpressedLamp.Color = 'g'; %to show user that the plot button has not been pressed

            result = PPGAnal.CalculateFiducial(app.Min1EditField.Value, app.Min2EditField.Value);

            if (~result)
                msgbox('Could not calcualte all fiducial points for segment', 'Error', 'error')
            elseif (anynan(PPGAnal.feature.total(PPGAnal.total_seg_idx,:)))
                msgbox('Could not calcualte all extra biomarkers for segment', 'Error', 'error')
            end

            app.PlotFiducial(PPGAnal);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            %%Value update for PPG
            PPGAnal.OnSpDnDpOff = [PPGAnal.seg(app.OnEditField.Value) PPGAnal.seg(app.SpEditField.Value) ...
                                   PPGAnal.seg(app.DnEditField.Value) PPGAnal.seg(app.DpEditField.Value) ...
                                   PPGAnal.seg(app.OffEditField.Value)];

            %Value update for VPG
            PPGAnal.uxvw = [PPGAnal.VPG(app.uEditField.Value) PPGAnal.VPG(app.xEditField.Value) ...
                            PPGAnal.VPG(app.vEditField.Value) PPGAnal.VPG(app.wEditField.Value)];

            %value update for APG
            PPGAnal.c_d_APG(PPGAnal.total_seg_idx) = app.canddpresentEditField.Value;
            PPGAnal.abcdef = [PPGAnal.APG(app.aEditField.Value) PPGAnal.APG(app.bEditField.Value) ...
                PPGAnal.APG(app.cEditField.Value) PPGAnal.APG(app.dEditField.Value) ...
                PPGAnal.APG(app.eEditField.Value) PPGAnal.APG(app.fEditField.Value)];
            
            %time variables of all waveform
            PPGAnal.OnSpDnDpOff_time  = [app.OnEditField.Value app.SpEditField.Value ...
                                         app.DnEditField.Value app.DpEditField.Value ...
                                         app.OffEditField.Value];
            PPGAnal.uxvw_time  = [app.uEditField.Value app.xEditField.Value ...
                                  app.vEditField.Value app.wEditField.Value];
            PPGAnal.abcdef_time = [app.aEditField.Value app.bEditField.Value ...
                                   app.cEditField.Value app.dEditField.Value ...
                                   app.eEditField.Value app.fEditField.Value];
            
            result = PPGAnal.UpdateFeatures();

            if (~result)
                msgbox('Could not calculate extra features for updated values', 'Error', 'error')
            end

            %update the figues
            app.PlotFiducial(PPGAnal);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if (app.PPGAnalObject.Next())

                %alert on 2nd last data
                if (app.PPGAnalObject.entry_idx+1 == app.PPGAnalObject.size_data(1))
                msgbox("This is second last data","Alert","warn");
                end
    
                %autosave onlast data
                if (app.PPGAnalObject.entry_idx > app.PPGAnalObject.size_data(1))
                msgbox("Auto saving","Alert","warn");
                SavematButtonPushed(app, event)
                ExitButtonPushed(app, event)
                end
    
                app.PlotPPG();
            end
        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
            app.PPGAnalObject.ProcessDataset();
            app.PlotPPG();
            app.PlotFiducial(app.PPGAnalObject);
        end

        % Button pushed function: SavematButton
        function SavematButtonPushed(app, event)
            app.PPGAnalObject.GenerateOutput(true);
        end

        % Button pushed function: SavecsvButton
        function SavecsvButtonPushed(app, event)
            app.PPGAnalObject.GenerateOutput(false);
        end

        % Callback function
        function PPGFeatUIFigureThemeChanged(app, event)
            app_color = [0.94 0.94 0.94];
            panel_color = [0.8 0.8 0.8];
            panel2_color = [0.65 0.65 0.65];

            if app.PPGFeatUIFigure.Theme.BaseColorStyle == "light"
                app.PPGFeatUIFigure.Color = app_color;
                app.Panel.BackgroundColor = panel_color;
                app.Panel_2.BackgroundColor = panel2_color;
            elseif app.PPGFeatUIFigure.Theme.BaseColorStyle == "dark"
                app.PPGFeatUIFigure.Color = fliplightness(app_color);
                app.Panel.BackgroundColor = fliplightness(panel_color);
                app.Panel_2.BackgroundColor = fliplightness(panel2_color);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PPGFeatUIFigure = uifigure('Visible', 'off');
            app.PPGFeatUIFigure.Name = 'PPGFeat';
            app.UIAxes = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes, 'Filtered PPG')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Normalized values')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes4 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes4, 'RAW PPG')
            xlabel(app.UIAxes4, 'Samples')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes2, 'PPG Segment')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes6 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes6, 'VPG')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes5, 'APG')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes3 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes3, 'JPG')
            zlabel(app.UIAxes3, 'Z')
            app.Panel = uipanel(app.PPGFeatUIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.FsHzEditFieldLabel = uilabel(app.Panel_2);
            app.FsHzEditFieldLabel.Text = 'Fs  (Hz)';
            app.FsHzEditField = uieditfield(app.Panel_2, 'numeric');
            app.FsHzEditField.Value = 1000;
            app.F1Label = uilabel(app.Panel_2);
            app.F1Label.Text = 'FL';
            app.FLEditField = uieditfield(app.Panel_2, 'numeric');
            app.FLEditField.Value = 0.4;
            app.FHEditFieldLabel = uilabel(app.Panel_2);
            app.FHEditFieldLabel.Text = 'FH';
            app.FHEditField = uieditfield(app.Panel_2, 'numeric');
            app.FHEditField.Value = 8;
            app.EnterSamplingfreqfilterfreqLabel = uilabel(app.Panel_2);
            app.EnterSamplingfreqfilterfreqLabel.Text = '  Enter Sampling freq & filter freq';
            app.ResampleEditFieldLabel = uilabel(app.Panel_2);
            app.ResampleEditFieldLabel.Text = 'Resample';
            app.ResampleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ResampleEditField.Value = 1000;
            app.ResampleCheck = uicheckbox(app.Panel_2);
            app.ResampleCheck.Text = '';
            app.SegmentEditFieldLabel = uilabel(app.Panel_2);
            app.SegmentEditFieldLabel.Text = 'Segment';
            app.SegmentEditField = uieditfield(app.Panel_2, 'numeric');
            app.SegmentEditField.Value = 10;
            app.SegmentCheck = uicheckbox(app.Panel_2);
            app.SegmentCheck.Text = '';
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = 's';
            app.LoadFileButton = uibutton(app.Panel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Plotallwaveformsusingmin1andmin2Label = uilabel(app.Panel);
            app.Plotallwaveformsusingmin1andmin2Label.Text = {'Plot all waveforms using '; 'min1 and min2'};
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel = uilabel(app.Panel);
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel.Text = {'Update feature points using'; 'modified user defined values'};
            app.NextButton = uibutton(app.Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.GotonextwaveformLabel = uilabel(app.Panel);
            app.GotonextwaveformLabel.Text = 'Go to next waveform ';
            app.SavematButton = uibutton(app.Panel, 'push');
            app.SavematButton.ButtonPushedFcn = createCallbackFcn(app, @SavematButtonPushed, true);
            app.SavematButton.Text = 'Save .mat';
            app.PlotpressedLampLabel = uilabel(app.Panel);
            app.PlotpressedLampLabel.Text = 'Plot pressed';
            app.PlotpressedLamp = uilamp(app.Panel);
            app.LoadFolderButton = uibutton(app.Panel, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.CorrelationLabel = uilabel(app.Panel);
            app.CorrelationLabel.Text = 'Correlation';
            app.CycleCorrSQIEditField = uieditfield(app.Panel, 'numeric');
            app.CycleCorrSQIEditField.Editable = 'off';
            app.SkewnessLabel = uilabel(app.Panel);
            app.SkewnessLabel.Text = 'Skewness';
            app.CycleSkewSQIEditField = uieditfield(app.Panel, 'numeric');
            app.CycleSkewSQIEditField.Editable = 'off';
            app.CycleSQILabel = uilabel(app.Panel);
            app.CycleSQILabel.Text = 'Cycle SQI';
            app.ProcessAllButton = uibutton(app.Panel, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Process every entry';
            app.ProcesseveryentrywithoutmanualinputLabel = uilabel(app.Panel);
            app.ProcesseveryentrywithoutmanualinputLabel.Text = {'Process every entry without '; 'manual input'};
            app.SavecsvButton = uibutton(app.Panel, 'push');
            app.SavecsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavecsvButtonPushed, true);
            app.SavecsvButton.Text = 'Save .csv';
            app.GenerateoutputLabel = uilabel(app.Panel);
            app.GenerateoutputLabel.Text = 'Generate output';
            app.CorrelationLabel_2 = uilabel(app.Panel);
            app.CorrelationLabel_2.Text = 'Combined';
            app.CycleCombSQIEditField = uieditfield(app.Panel, 'numeric');
            app.CycleCombSQIEditField.Editable = 'off';
            app.SegmentSQILabel = uilabel(app.Panel);
            app.SegmentSQILabel.Text = 'Segment SQI';
            app.CorrelationLabel_3 = uilabel(app.Panel);
            app.CorrelationLabel_3.Text = 'Correlation';
            app.SegCorrSQIEditField = uieditfield(app.Panel, 'numeric');
            app.SegCorrSQIEditField.Editable = 'off';
            app.SkewnessLabel_2 = uilabel(app.Panel);
            app.SkewnessLabel_2.Text = 'Skewness';
            app.SegSkewSQIEditField = uieditfield(app.Panel, 'numeric');
            app.SegSkewSQIEditField.Editable = 'off';
            app.CorrelationLabel_4 = uilabel(app.Panel);
            app.CorrelationLabel_4.Text = 'Combined';
            app.SegCombSQIEdit = uieditfield(app.Panel, 'numeric');
            app.SegCombSQIEdit.Editable = 'off';
            app.Min1EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Min1EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min1EditFieldLabel.Text = 'Min1';
            app.Min2EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min2EditFieldLabel.Text = 'Min2';
            app.Min2EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.IDLabel = uilabel(app.PPGFeatUIFigure);
            app.IDLabel.Text = 'ID ';
            app.IDEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.IDEditField.Editable = 'off';
            app.DataTextAreaLabel = uilabel(app.PPGFeatUIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.PPGFeatUIFigure);
            app.DataTextArea.Editable = 'off';
            app.SLabel = uilabel(app.PPGFeatUIFigure);
            app.SLabel.Text = 'dp';
            app.DpEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.DpEditField.AllowEmpty = 'on';
            app.dnLabel = uilabel(app.PPGFeatUIFigure);
            app.dnLabel.Text = 'dn';
            app.DnEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.DnEditField.AllowEmpty = 'on';
            app.DLabel = uilabel(app.PPGFeatUIFigure);
            app.DLabel.Text = 'sp';
            app.SpEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.SpEditField.AllowEmpty = 'on';
            app.onLabel = uilabel(app.PPGFeatUIFigure);
            app.onLabel.Text = 'on';
            app.OnEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.OnEditField.AllowEmpty = 'on';
            app.yLabel = uilabel(app.PPGFeatUIFigure);
            app.yLabel.Text = 'v';
            app.wEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.wEditField.AllowEmpty = 'on';
            app.vEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.vEditField.AllowEmpty = 'on';
            app.xLabel = uilabel(app.PPGFeatUIFigure);
            app.xLabel.Text = 'x';
            app.zLabel = uilabel(app.PPGFeatUIFigure);
            app.zLabel.Text = 'w';
            app.xEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.xEditField.AllowEmpty = 'on';
            app.wLabel = uilabel(app.PPGFeatUIFigure);
            app.wLabel.Text = 'u';
            app.uEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.uEditField.AllowEmpty = 'on';
            app.Yes0NoLabel = uilabel(app.PPGFeatUIFigure);
            app.Yes0NoLabel.Text = '1: Yes, 0: No';
            app.aEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.aEditField.AllowEmpty = 'on';
            app.bEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.bEditField.AllowEmpty = 'on';
            app.cmLabel = uilabel(app.PPGFeatUIFigure);
            app.cmLabel.Text = 'c ';
            app.cEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.cEditField.AllowEmpty = 'on';
            app.dEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.dEditFieldLabel.Text = 'd ';
            app.dEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.dEditField.AllowEmpty = 'on';
            app.eEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.eEditField.AllowEmpty = 'on';
            app.fEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.fEditField.AllowEmpty = 'on';
            app.canddpresentEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.canddpresentEditFieldLabel.Text = 'c and d present ';
            app.canddpresentEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.SLabel_2 = uilabel(app.PPGFeatUIFigure);
            app.SLabel_2.Text = 'off';
            app.OffEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.OffEditField.AllowEmpty = 'on';
            % Show the figure after all components are created
            app.PPGFeatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPGFeat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGFeatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGFeatUIFigure)
        end
    end
end